classdef otro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GraficarButton              matlab.ui.control.Button
        PararButton                 matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        ExportardatosaExcelButton   matlab.ui.control.Button
        ConexinPanel                matlab.ui.container.Panel
        PlacaDropDownLabel          matlab.ui.control.Label
        PlacaDropDown               matlab.ui.control.DropDown
        PuertoDropDownLabel         matlab.ui.control.Label
        PuertoDropDown              matlab.ui.control.DropDown
        ConectandoLabel             matlab.ui.control.Label
        ConectarButton              matlab.ui.control.Button
        ConectandoLamp              matlab.ui.control.Lamp
        DuracinensegEditFieldLabel  matlab.ui.control.Label
        DuracinensegEditField       matlab.ui.control.EditField
        CerrarButton                matlab.ui.control.Button
        FrecuenciaCardiacaLEDensistemaPanel  matlab.ui.container.Panel
        BradicardiaLampLabel        matlab.ui.control.Label
        BradicardiaLamp             matlab.ui.control.Lamp
        NormalLampLabel             matlab.ui.control.Label
        NormalLamp                  matlab.ui.control.Lamp
        TaquicardiaLampLabel        matlab.ui.control.Label
        TaquicardiaLamp             matlab.ui.control.Lamp
        LPMLabel                    matlab.ui.control.Label
        LPMLabel_2                  matlab.ui.control.Label
        LPMLabel_3                  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM5",9600,"Timeout",1000);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            cantidad = app.DuracinensegEditField.Value
            switch app.PuertoDropDown.Value
                case 'COM1'
                    puerto = 'COM1';
                case 'COM5'
                    puerto = 'COM5';
            end
            switch app.PlacaDropDown.Value
                case 'Mega2560'
                    placa = 'Mega2560';
                case 'Uno'
                    placa = 'Uno';
            end
            
            try 
                a=arduino(puerto,placa);
            catch 
                warning('No se pudo conectar, intenta de nuevo');
                app.ConectandoLamp.Color = 'red';
                app.ConectandoLabel.Text = 'No conectado';
            end
            app.ConectandoLamp.Color = 'green';
            app.ConectandoLabel.Text = 'Conectado';
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            global parar datos 
            parar = 0;
            cantidad = app.DuracinensegEditField.Value;
            muestras = 700;
            frecuencia = 0;
            contador = 1;     
            while contador <= muestras
         
              if parar == 1
                    break;
                end
                valorADC = str2double(readline(app.s));
                const(contador) = 5;
                frecuencia(contador) = valorADC(1);
                plot(app.UIAxes,frecuencia,'r');
                app.UIAxes.XLim = [0 contador];
                app.UIAxes.YLim = [0 700];
                
                tiempo(contador) = contador;
                
                datos = [tiempo;frecuencia];
                app.UITable.Data = datos';
                
                contador = contador + 1;
            end
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            global parar
            parar = 1;

        end

        % Button pushed function: ExportardatosaExcelButton
        function ExportardatosaExcelButtonPushed(app, event)
            global datos
            writematrix(datos','datos.xls');
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Tiempo'; 'Voltaje'};
            app.UITable.RowName = {};
            app.ExportardatosaExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportardatosaExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosaExcelButtonPushed, true);
            app.ExportardatosaExcelButton.Text = 'Exportar datos a Excel';
            app.ConexinPanel = uipanel(app.UIFigure);
            app.ConexinPanel.Title = 'Conexión';
            app.PlacaDropDownLabel = uilabel(app.ConexinPanel);
            app.PlacaDropDownLabel.Text = 'Placa';
            app.PlacaDropDown = uidropdown(app.ConexinPanel);
            app.PlacaDropDown.Items = {'Mega2560', 'Uno'};
            app.PlacaDropDown.Value = 'Mega2560';
            app.PuertoDropDownLabel = uilabel(app.ConexinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ConexinPanel);
            app.PuertoDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.PuertoDropDown.Value = 'COM5';
            app.ConectandoLabel = uilabel(app.ConexinPanel);
            app.ConectandoLabel.Text = 'Conectando';
            app.ConectarButton = uibutton(app.ConexinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConectandoLamp = uilamp(app.ConexinPanel);
            app.DuracinensegEditFieldLabel = uilabel(app.ConexinPanel);
            app.DuracinensegEditFieldLabel.Text = 'Duración (en seg)';
            app.DuracinensegEditField = uieditfield(app.ConexinPanel, 'text');
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.FrecuenciaCardiacaLEDensistemaPanel = uipanel(app.UIFigure);
            app.FrecuenciaCardiacaLEDensistemaPanel.Title = 'Frecuencia Cardiaca (LED en sistema)';
            app.BradicardiaLampLabel = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.BradicardiaLampLabel.Text = 'Bradicardia';
            app.BradicardiaLamp = uilamp(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.NormalLampLabel = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.TaquicardiaLampLabel = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.TaquicardiaLampLabel.Text = 'Taquicardia';
            app.TaquicardiaLamp = uilamp(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.LPMLabel = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.LPMLabel.Text = '>60 LPM';
            app.LPMLabel_2 = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.LPMLabel_2.Text = '60 - 100 LPM';
            app.LPMLabel_3 = uilabel(app.FrecuenciaCardiacaLEDensistemaPanel);
            app.LPMLabel_3.Text = '<100 LPM';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG')
            xlabel(app.UIAxes, 'Tiempo (ms)')
            ylabel(app.UIAxes, 'Amplitud')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = otro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end