classdef otro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GraficarButton              matlab.ui.control.Button
        PararButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        ExportardatosaExcelButton   matlab.ui.control.Button
        ConexionPanel               matlab.ui.container.Panel
        PlacaDropDownLabel          matlab.ui.control.Label
        PlacaDropDown               matlab.ui.control.DropDown
        PuertoDropDownLabel         matlab.ui.control.Label
        PuertoDropDown              matlab.ui.control.DropDown
        ConectandoLabel             matlab.ui.control.Label
        ConectarButton              matlab.ui.control.Button
        ConectandoLamp              matlab.ui.control.Lamp
        DuracinensegEditFieldLabel  matlab.ui.control.Label
        DuracinensegEditField       matlab.ui.control.EditField
        CerrarButton                matlab.ui.control.Button
        FrecuenciaCardiacaPanel     matlab.ui.container.Panel
        BradicardiaLampLabel        matlab.ui.control.Label
        BradicardiaLamp             matlab.ui.control.Lamp
        NormalLampLabel             matlab.ui.control.Label
        NormalLamp                  matlab.ui.control.Lamp
        TaquicardiaLampLabel        matlab.ui.control.Label
        TaquicardiaLamp             matlab.ui.control.Lamp
        BPMLabel                    matlab.ui.control.Label
        BPMLabel_2                  matlab.ui.control.Label
        BPMLabel_3                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM5",9600,"Timeout",1000);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            cantidad = app.DuracinensegEditField.Value
            switch app.PuertoDropDown.Value
                case 'COM1'
                    puerto = 'COM1';
                case 'COM5'
                    puerto = 'COM5';
            end
            switch app.PlacaDropDown.Value
                case 'Mega2560'
                    placa = 'Mega2560';
                case 'Uno'
                    placa = 'Uno';
            end
            
            try 
                a=arduino(puerto,placa);
            catch 
                warning('No se pudo conectar, intenta de nuevo');
                app.ConectandoLamp.Color = 'red';
                app.ConectandoLabel.Text = 'No conectado';
            end
            app.ConectandoLamp.Color = 'green';
            app.ConectandoLabel.Text = 'Conectado';
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            global parar datos 
            parar = 0;
            cantidad = app.DuracinensegEditField.Value;
            muestras = 700;
            frecuencia = 0;
            contador = 1;     
            while contador <= muestras
         
              if parar == 1
                    break;
                end
                valorADC = str2double(readline(app.s));
                const(contador) = 5;
                frecuencia(contador) = valorADC(1);
                plot(app.UIAxes,frecuencia,'r');
                app.UIAxes.XLim = [0 contador];
                app.UIAxes.YLim = [0 700];
                
                tiempo(contador) = contador;
                
                datos = [tiempo;frecuencia];
                app.UITable.Data = datos';
                
                contador = contador + 1;
            end
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            global parar
            parar = 1;

        end

        % Button pushed function: ExportardatosaExcelButton
        function ExportardatosaExcelButtonPushed(app, event)
            global datos
            writematrix(datos','datos.xls');
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG')
            xlabel(app.UIAxes, 'Tiempo (ms)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Tiempo'; 'Voltaje'};
            app.UITable.RowName = {};
            app.ExportardatosaExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportardatosaExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosaExcelButtonPushed, true);
            app.ExportardatosaExcelButton.Text = 'Exportar datos a Excel';
            app.ConexionPanel = uipanel(app.UIFigure);
            app.ConexionPanel.Title = 'Conexion';
            app.PlacaDropDownLabel = uilabel(app.ConexionPanel);
            app.PlacaDropDownLabel.Text = 'Placa';
            app.PlacaDropDown = uidropdown(app.ConexionPanel);
            app.PlacaDropDown.Items = {'Mega2560', 'Uno'};
            app.PlacaDropDown.Value = 'Mega2560';
            app.PuertoDropDownLabel = uilabel(app.ConexionPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ConexionPanel);
            app.PuertoDropDown.Items = {'COM1', 'COM5'};
            app.PuertoDropDown.Value = 'COM5';
            app.ConectandoLabel = uilabel(app.ConexionPanel);
            app.ConectandoLabel.Text = 'Conectando';
            app.ConectarButton = uibutton(app.ConexionPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConectandoLamp = uilamp(app.ConexionPanel);
            app.DuracinensegEditFieldLabel = uilabel(app.ConexionPanel);
            app.DuracinensegEditFieldLabel.Text = 'Duración (en seg)';
            app.DuracinensegEditField = uieditfield(app.ConexionPanel, 'text');
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.FrecuenciaCardiacaPanel = uipanel(app.UIFigure);
            app.FrecuenciaCardiacaPanel.Title = 'Frecuencia Cardiaca';
            app.BradicardiaLampLabel = uilabel(app.FrecuenciaCardiacaPanel);
            app.BradicardiaLampLabel.Text = 'Bradicardia';
            app.BradicardiaLamp = uilamp(app.FrecuenciaCardiacaPanel);
            app.NormalLampLabel = uilabel(app.FrecuenciaCardiacaPanel);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.FrecuenciaCardiacaPanel);
            app.TaquicardiaLampLabel = uilabel(app.FrecuenciaCardiacaPanel);
            app.TaquicardiaLampLabel.Text = 'Taquicardia';
            app.TaquicardiaLamp = uilamp(app.FrecuenciaCardiacaPanel);
            app.BPMLabel = uilabel(app.FrecuenciaCardiacaPanel);
            app.BPMLabel.Text = '>60 BPM';
            app.BPMLabel_2 = uilabel(app.FrecuenciaCardiacaPanel);
            app.BPMLabel_2.Text = '60 - 100 BPM';
            app.BPMLabel_3 = uilabel(app.FrecuenciaCardiacaPanel);
            app.BPMLabel_3.Text = '<100 BPM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = otro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end