classdef interfazproyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InicioPanel              matlab.ui.container.Panel
        ConectarButton           matlab.ui.control.Button
        PlacaDropDownLabel       matlab.ui.control.Label
        PlacaDropDown            matlab.ui.control.DropDown
        ConectandoLamp           matlab.ui.control.Lamp
        ConectandoLabel          matlab.ui.control.Label
        PuertoEditFieldLabel     matlab.ui.control.Label
        PuertoEditField          matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        FrecuenciacardiacaPanel  matlab.ui.container.Panel
        PinEditFieldLabel        matlab.ui.control.Label
        PinEditField             matlab.ui.control.EditField
        GraficarCheckBox         matlab.ui.control.CheckBox
        CerrarButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            puerto = app.PuertoEditField.Value;
            switch app.PlacaDropDown.Value
                case 'Uno'
                    placa = 'Uno';
                case 'Mega2560'
                    placa = 'Mega2560';
            end
            
            try
                a=arduino(puerto,placa);
            catch 
                warning('No se pudo conectar, intenta de nuevo');
                app.ConectandoLamp.Color = 'red';
                app.ConectandoLabel.Text = 'No conectado'; 
            end
            app.ConectandoLamp.Color = 'green';
            app.ConectandoLabel.Text = 'Conectado';
        end

        % Value changed function: GraficarCheckBox
        function GraficarCheckBoxValueChanged(app, event)
            a = arduino;
            pin = app.PinEditField.Value;
            t = 0;
            delay = 0.1;
            vt = 10;
            hold(app.UIAxes)
            
            while app.GraficarCheckBox.Value==1
                app.UIAxes.XLim = [0 t+vt];
                app.UIAxes.YLim = [0 100];
                f=readVoltage(a,pin);
                pause(delay);
                plot(app.UIAxes,t,f,'o')
                t=t+delay;
            end
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InicioPanel = uipanel(app.UIFigure);
            app.InicioPanel.Title = 'Inicio';
            app.ConectarButton = uibutton(app.InicioPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PlacaDropDownLabel = uilabel(app.InicioPanel);
            app.PlacaDropDownLabel.Text = 'Placa';
            app.PlacaDropDown = uidropdown(app.InicioPanel);
            app.PlacaDropDown.Items = {'Mega2560', 'Uno'};
            app.PlacaDropDown.Value = 'Mega2560';
            app.ConectandoLamp = uilamp(app.InicioPanel);
            app.ConectandoLabel = uilabel(app.InicioPanel);
            app.ConectandoLabel.Text = 'Conectando';
            app.PuertoEditFieldLabel = uilabel(app.InicioPanel);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.InicioPanel, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrecuenciacardiacaPanel = uipanel(app.UIFigure);
            app.FrecuenciacardiacaPanel.Title = 'Frecuencia cardiaca';
            app.PinEditFieldLabel = uilabel(app.FrecuenciacardiacaPanel);
            app.PinEditFieldLabel.Text = 'Pin';
            app.PinEditField = uieditfield(app.FrecuenciacardiacaPanel, 'text');
            app.GraficarCheckBox = uicheckbox(app.FrecuenciacardiacaPanel);
            app.GraficarCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraficarCheckBoxValueChanged, true);
            app.GraficarCheckBox.Text = 'Graficar';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazproyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end